classdef robotLabProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        TabGroup           matlab.ui.container.TabGroup
        GenelTab           matlab.ui.container.Tab
        GenelGrid          matlab.ui.container.GridLayout
        bolumTitle         matlab.ui.control.Label
        dersTitle          matlab.ui.control.Label
        logo               matlab.ui.control.Image
        rollValue          matlab.ui.control.Label
        pitchValue         matlab.ui.control.Label
        yawValue           matlab.ui.control.Label
        rollTitle          matlab.ui.control.Label
        pitchTitle         matlab.ui.control.Label
        yawTitle           matlab.ui.control.Label
        zValue             matlab.ui.control.Label
        yValue             matlab.ui.control.Label
        xValue             matlab.ui.control.Label
        zTitle             matlab.ui.control.Label
        yTitle             matlab.ui.control.Label
        xTitle             matlab.ui.control.Label
        cizdir             matlab.ui.control.Button
        hesapla            matlab.ui.control.Button
        line8Degree        matlab.ui.control.NumericEditField
        line7Degree        matlab.ui.control.NumericEditField
        line6Degree        matlab.ui.control.NumericEditField
        line5Degree        matlab.ui.control.NumericEditField
        line4Degree        matlab.ui.control.NumericEditField
        line3Degree        matlab.ui.control.NumericEditField
        line2Degree        matlab.ui.control.NumericEditField
        line1Degree        matlab.ui.control.NumericEditField
        line8Slider        matlab.ui.control.Slider
        line7Slider        matlab.ui.control.Slider
        line6Slider        matlab.ui.control.Slider
        line5Slider        matlab.ui.control.Slider
        line4Slider        matlab.ui.control.Slider
        line3Slider        matlab.ui.control.Slider
        line2Slider        matlab.ui.control.Slider
        line1Slider        matlab.ui.control.Slider
        lineType8          matlab.ui.control.DropDown
        lineType7          matlab.ui.control.DropDown
        lineType6          matlab.ui.control.DropDown
        lineType5          matlab.ui.control.DropDown
        lineType4          matlab.ui.control.DropDown
        lineType3          matlab.ui.control.DropDown
        lineType2          matlab.ui.control.DropDown
        lineType1          matlab.ui.control.DropDown
        q8                 matlab.ui.control.NumericEditField
        d8                 matlab.ui.control.NumericEditField
        alp8               matlab.ui.control.NumericEditField
        a8                 matlab.ui.control.NumericEditField
        q7                 matlab.ui.control.NumericEditField
        d7                 matlab.ui.control.NumericEditField
        alp7               matlab.ui.control.NumericEditField
        a7                 matlab.ui.control.NumericEditField
        q6                 matlab.ui.control.NumericEditField
        d6                 matlab.ui.control.NumericEditField
        alp6               matlab.ui.control.NumericEditField
        a6                 matlab.ui.control.NumericEditField
        q5                 matlab.ui.control.NumericEditField
        d5                 matlab.ui.control.NumericEditField
        alp5               matlab.ui.control.NumericEditField
        a5                 matlab.ui.control.NumericEditField
        q4                 matlab.ui.control.NumericEditField
        d4                 matlab.ui.control.NumericEditField
        alp4               matlab.ui.control.NumericEditField
        a4                 matlab.ui.control.NumericEditField
        q3                 matlab.ui.control.NumericEditField
        d3                 matlab.ui.control.NumericEditField
        alp3               matlab.ui.control.NumericEditField
        a3                 matlab.ui.control.NumericEditField
        q2                 matlab.ui.control.NumericEditField
        d2                 matlab.ui.control.NumericEditField
        alp2               matlab.ui.control.NumericEditField
        a2                 matlab.ui.control.NumericEditField
        q1                 matlab.ui.control.NumericEditField
        d1                 matlab.ui.control.NumericEditField
        alp1               matlab.ui.control.NumericEditField
        a1                 matlab.ui.control.NumericEditField
        line8Title         matlab.ui.control.Label
        line7Title         matlab.ui.control.Label
        line6Title         matlab.ui.control.Label
        line5Title         matlab.ui.control.Label
        line4Title         matlab.ui.control.Label
        line3Title         matlab.ui.control.Label
        line2Title         matlab.ui.control.Label
        line1Title         matlab.ui.control.Label
        EklemDeskeniTitle  matlab.ui.control.Label
        EklemTipiTitle     matlab.ui.control.Label
        tetaDegTitle       matlab.ui.control.Label
        dTitle             matlab.ui.control.Label
        alfaDegTitle       matlab.ui.control.Label
        aTitle             matlab.ui.control.Label
        TableTitle         matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        SimulasyonTab      matlab.ui.container.Tab
        simGrid            matlab.ui.container.GridLayout
        sim_rollValue      matlab.ui.control.Label
        sim_pitchValue     matlab.ui.control.Label
        sim_yawValue       matlab.ui.control.Label
        sim_rollTitle      matlab.ui.control.Label
        sim_pitchTitle     matlab.ui.control.Label
        sim_yawTitle       matlab.ui.control.Label
        sim_zValue         matlab.ui.control.Label
        sim_yValue         matlab.ui.control.Label
        sim_xValue         matlab.ui.control.Label
        sim_zTitle         matlab.ui.control.Label
        sim_yTitle         matlab.ui.control.Label
        sim_xTitle         matlab.ui.control.Label
        bolumTitle_2       matlab.ui.control.Label
        dersTitle_2        matlab.ui.control.Label
        logo_2             matlab.ui.control.Image
        sim_durdur         matlab.ui.control.Button
        sim_calistir       matlab.ui.control.Button
        sim_line6Degree    matlab.ui.control.NumericEditField
        sim_line5Degree    matlab.ui.control.NumericEditField
        sim_line4Degree    matlab.ui.control.NumericEditField
        sim_line3Degree    matlab.ui.control.NumericEditField
        sim_line2Degree    matlab.ui.control.NumericEditField
        sim_line1Degree    matlab.ui.control.NumericEditField
        sim_line6Slider    matlab.ui.control.Slider
        sim_line5Slider    matlab.ui.control.Slider
        sim_line4Slider    matlab.ui.control.Slider
        sim_line3Slider    matlab.ui.control.Slider
        sim_line2Slider    matlab.ui.control.Slider
        sim_line1Slider    matlab.ui.control.Slider
        sim_q6             matlab.ui.control.NumericEditField
        sim_d6             matlab.ui.control.NumericEditField
        sim_alp6           matlab.ui.control.NumericEditField
        sim_a6             matlab.ui.control.NumericEditField
        sim_q5             matlab.ui.control.NumericEditField
        sim_d5             matlab.ui.control.NumericEditField
        sim_alp5           matlab.ui.control.NumericEditField
        sim_a5             matlab.ui.control.NumericEditField
        sim_q4             matlab.ui.control.NumericEditField
        sim_d4             matlab.ui.control.NumericEditField
        sim_alp4           matlab.ui.control.NumericEditField
        sim_a4             matlab.ui.control.NumericEditField
        sim_q3             matlab.ui.control.NumericEditField
        sim_d3             matlab.ui.control.NumericEditField
        sim_alp3           matlab.ui.control.NumericEditField
        sim_a3             matlab.ui.control.NumericEditField
        sim_q2             matlab.ui.control.NumericEditField
        sim_d2             matlab.ui.control.NumericEditField
        sim_alp2           matlab.ui.control.NumericEditField
        sim_a2             matlab.ui.control.NumericEditField
        sim_q1             matlab.ui.control.NumericEditField
        sim_d1             matlab.ui.control.NumericEditField
        sim_alp1           matlab.ui.control.NumericEditField
        sim_a1             matlab.ui.control.NumericEditField
        simLine6Title      matlab.ui.control.Label
        simLine5Title      matlab.ui.control.Label
        simLine4Title      matlab.ui.control.Label
        simLine3Title      matlab.ui.control.Label
        simLine2Title      matlab.ui.control.Label
        simLine1Title      matlab.ui.control.Label
        simDegiskenTitle   matlab.ui.control.Label
        simTetaDegTitle    matlab.ui.control.Label
        simDTitle          matlab.ui.control.Label
        simAlfaDegTitle    matlab.ui.control.Label
        simATitle          matlab.ui.control.Label
        simTableTitle      matlab.ui.control.Label
        sim_UIAxes         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
       function lineOn(app,i)
            switch i
                case 1
                    app.a1.Enable="on";
                    app.a1.Editable="on";
                    app.alp1.Enable="on";
                    app.alp1.Editable="on";
                    app.d1.Enable="on";
                    app.d1.Editable="on";
                    app.q1.Enable="on";
                    app.q1.Editable="on";
                    app.line1Slider.Enable="on";
                    app.line1Degree.Enable="on";
                    app.line1Degree.Editable="on";
                    app.cizdir.Enable="on";
                case 2
                    app.a2.Enable="on";
                    app.a2.Editable="on";
                    app.alp2.Enable="on";
                    app.alp2.Editable="on";
                    app.d2.Enable="on";
                    app.d2.Editable="on";
                    app.q2.Enable="on";
                    app.q2.Editable="on";
                    app.line2Slider.Enable="on";
                    app.line2Degree.Enable="on";
                    app.line2Degree.Editable="on";
                case 3
                    app.a3.Enable="on";
                    app.a3.Editable="on";
                    app.alp3.Enable="on";
                    app.alp3.Editable="on";
                    app.d3.Enable="on";
                    app.d3.Editable="on";
                    app.q3.Enable="on";
                    app.q3.Editable="on";
                    app.line3Slider.Enable="on";
                    app.line3Degree.Enable="on";
                    app.line3Degree.Editable="on";
                case 4
                    app.a4.Enable="on";
                    app.a4.Editable="on";
                    app.alp4.Enable="on";
                    app.alp4.Editable="on";
                    app.d4.Enable="on";
                    app.d4.Editable="on";
                    app.q4.Enable="on";
                    app.q4.Editable="on";
                    app.line4Slider.Enable="on";
                    app.line4Degree.Enable="on";
                    app.line4Degree.Editable="on";
                case 5
                    app.a5.Enable="on";
                    app.a5.Editable="on";
                    app.alp5.Enable="on";
                    app.alp5.Editable="on";
                    app.d5.Enable="on";
                    app.d5.Editable="on";
                    app.q5.Enable="on";
                    app.q5.Editable="on";
                    app.line5Slider.Enable="on";
                    app.line5Degree.Enable="on";
                    app.line5Degree.Editable="on";
                case 6
                    app.a6.Enable="on";
                    app.a6.Editable="on";
                    app.alp6.Enable="on";
                    app.alp6.Editable="on";
                    app.d6.Enable="on";
                    app.d6.Editable="on";
                    app.q6.Enable="on";
                    app.q6.Editable="on";
                    app.line6Slider.Enable="on";
                    app.line6Degree.Enable="on";
                    app.line6Degree.Editable="on";
                case 7
                    app.a7.Enable="on";
                    app.a7.Editable="on";
                    app.alp7.Enable="on";
                    app.alp7.Editable="on";
                    app.d7.Enable="on";
                    app.d7.Editable="on";
                    app.q7.Enable="on";
                    app.q7.Editable="on";
                    app.line7Slider.Enable="on";
                    app.line7Degree.Enable="on";
                    app.line7Degree.Editable="on";
                case 8
                    app.a8.Enable="on";
                    app.a8.Editable="on";
                    app.alp8.Enable="on";
                    app.alp8.Editable="on";
                    app.d8.Enable="on";
                    app.d8.Editable="on";
                    app.q8.Enable="on";
                    app.q8.Editable="on";
                    app.line8Slider.Enable="on";
                    app.line8Degree.Enable="on";
                    app.line8Degree.Editable="on";
            end            
        end
        
        function lineOff(app,i)
            switch i
                case 1
                    app.a1.Enable="off";
                    app.a1.Editable="off";
                    app.alp1.Enable="off";
                    app.alp1.Editable="off";
                    app.d1.Enable="off";
                    app.d1.Editable="off";
                    app.q1.Enable="off";
                    app.q1.Editable="off";
                    app.line1Slider.Enable="off";
                    app.line1Slider.Value=0;
                    app.line1Degree.Enable="off";
                    app.line1Degree.Editable="off";
                    app.line1Degree.Value=0;
                    app.lineType1.Value = 0;
                    app.cizdir.Enable="off";
                case 2
                    app.a2.Enable="off";
                    app.a2.Editable="off";
                    app.alp2.Enable="off";
                    app.alp2.Editable="off";
                    app.d2.Enable="off";
                    app.d2.Editable="off";
                    app.q2.Enable="off";
                    app.q2.Editable="off";
                    app.line2Slider.Enable="off";
                    app.line2Slider.Value=0;
                    app.line2Degree.Enable="off";
                    app.line2Degree.Editable="off";
                    app.line2Degree.Value=0;
                    app.lineType2.Value = 0;
                case 3
                    app.a3.Enable="off";
                    app.a3.Editable="off";
                    app.alp3.Enable="off";
                    app.alp3.Editable="off";
                    app.d3.Enable="off";
                    app.d3.Editable="off";
                    app.q3.Enable="off";
                    app.q3.Editable="off";
                    app.line3Slider.Enable="off";
                    app.line3Slider.Value=0;
                    app.line3Degree.Enable="off";
                    app.line3Degree.Editable="off";
                    app.line3Degree.Value=0;
                    app.lineType3.Value = 0;
                case 4
                    app.a4.Enable="off";
                    app.a4.Editable="off";
                    app.alp4.Enable="off";
                    app.alp4.Editable="off";
                    app.d4.Enable="off";
                    app.d4.Editable="off";
                    app.q4.Enable="off";
                    app.q4.Editable="off";
                    app.line4Slider.Enable="off";
                    app.line4Slider.Value=0;
                    app.line4Degree.Enable="off";
                    app.line4Degree.Editable="off";
                    app.line4Degree.Value=0;
                    app.lineType4.Value = 0;
                case 5
                    app.a5.Enable="off";
                    app.a5.Editable="off";
                    app.alp5.Enable="off";
                    app.alp5.Editable="off";
                    app.d5.Enable="off";
                    app.d5.Editable="off";
                    app.q5.Enable="off";
                    app.q5.Editable="off";
                    app.line5Slider.Enable="off";
                    app.line5Slider.Value=0;
                    app.line5Degree.Enable="off";
                    app.line5Degree.Editable="off";
                    app.line5Degree.Value=0;
                    app.lineType5.Value = 0;
                case 6
                    app.a6.Enable="off";
                    app.a6.Editable="off";
                    app.alp6.Enable="off";
                    app.alp6.Editable="off";
                    app.d6.Enable="off";
                    app.d6.Editable="off";
                    app.q6.Enable="off";
                    app.q6.Editable="off";
                    app.line6Slider.Enable="off";
                    app.line6Slider.Value=0;
                    app.line6Degree.Enable="off";
                    app.line6Degree.Editable="off";
                    app.line6Degree.Value=0;
                    app.lineType6.Value = 0;
                case 7
                    app.a7.Enable="off";
                    app.a7.Editable="off";
                    app.alp7.Enable="off";
                    app.alp7.Editable="off";
                    app.d7.Enable="off";
                    app.d7.Editable="off";
                    app.q7.Enable="off";
                    app.q7.Editable="off";
                    app.line7Slider.Enable="off";
                    app.line7Slider.Value=0;
                    app.line7Degree.Enable="off";
                    app.line7Degree.Editable="off";
                    app.line7Degree.Value=0;
                    app.lineType7.Value = 0;
                case 8
                    app.a8.Enable="off";
                    app.a8.Editable="off";
                    app.alp8.Enable="off";
                    app.alp8.Editable="off";
                    app.d8.Enable="off";
                    app.d8.Editable="off";
                    app.q8.Enable="off";
                    app.q8.Editable="off";
                    app.line8Slider.Enable="off";
                    app.line8Slider.Value=0;
                    app.line8Degree.Enable="off";
                    app.line8Degree.Editable="off";
                    app.line8Degree.Value=0;
                    app.lineType8.Value = 0;
            end    
        end


        function lineDraw(app, x1, y1, z1, x2, y2, z2,type)
            hold(app.UIAxes, 'on');
            plot3(app.UIAxes, [x1, x2], [y1, y2], [z1, z2], 'LineWidth', 6);
            switch type
                case 'R' 
                    scatter3(app.UIAxes, x1, y1, z1, 'Marker', 'o', 'SizeData', 100, 'MarkerFaceColor', 'flat');
                case 'P'
                    scatter3(app.UIAxes, x1, y1, z1, 'Marker', 's', 'SizeData', 100, 'MarkerFaceColor', 'flat');
                case 'S'
                    scatter3(app.UIAxes, x1, y1, z1, 'Marker', '^', 'SizeData', 100, 'MarkerFaceColor', 'flat');
            end
            view(app.UIAxes,45,45);
            hold(app.UIAxes, 'off');
        end
        
        function updateGraph(app)
            cla(app.UIAxes);

            dosyaAdi = 'R-T01.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction1 = str2func(['@(sym1) ' sembolikIfade]);
                sym1Val = app.line1Degree.Value;
                matris = symbolicFunction1(sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris(1,4));
                app.yValue.Text = num2str(matris(2,4));
                app.zValue.Text = num2str(matris(3,4));

                R = matris(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,0,0,0,matris(1,4),matris(2,4),matris(3,4),'R');

            end

            dosyaAdi = 'P-T01.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction2 = str2func(['@(sym1) ' sembolikIfade]);
                sym1Val = app.line1Degree.Value;
                matris = symbolicFunction2(sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris(1,4));
                app.yValue.Text = num2str(matris(2,4));
                app.zValue.Text = num2str(matris(3,4));

                R = matris(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,0,0,0,matris(1,4),matris(2,4),matris(3,4),'P');
            end

            dosyaAdi = 'S-T01.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                matris = fgetl(dosya);
                matris=str2num(matris);
                fclose(dosya);

                app.xValue.Text = num2str(matris(1,4));
                app.yValue.Text = num2str(matris(2,4));
                app.zValue.Text = num2str(matris(3,4));

                R = matris(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,0,0,0,matris(1,4),matris(2,4),matris(3,4),'S');
            end


            dosyaAdi = 'R-T02.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction3 = str2func(['@(sym2,sym1) ' sembolikIfade]);
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris2 = symbolicFunction3(sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris2(1,4));
                app.yValue.Text = num2str(matris2(2,4));
                app.zValue.Text = num2str(matris2(3,4));

                R = matris2(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris(1,4),matris(2,4),matris(3,4),matris2(1,4),matris2(2,4),matris2(3,4),'R');
            end

            dosyaAdi = 'P-T02.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction4 = str2func(['@(sym2,sym1) ' sembolikIfade]);
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris2 = symbolicFunction4(sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris2(1,4));
                app.yValue.Text = num2str(matris2(2,4));
                app.zValue.Text = num2str(matris2(3,4));

                R = matris2(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris(1,4),matris(2,4),matris(3,4),matris2(1,4),matris2(2,4),matris2(3,4),'P');
            end

            dosyaAdi = 'S-T02.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                matris2 = fgetl(dosya);
                matris2=str2num(matris);
                fclose(dosya);

                app.xValue.Text = num2str(matris2(1,4));
                app.yValue.Text = num2str(matris2(2,4));
                app.zValue.Text = num2str(matris2(3,4));

                R = matris2(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris(1,4),matris(2,4),matris(3,4),matris2(1,4),matris2(2,4),matris2(3,4),'S');
            end


            dosyaAdi = 'R-T03.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction5 = str2func(['@(sym3,sym2,sym1) ' sembolikIfade]);
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris3 = symbolicFunction5(sym3Val,sym2Val,sym1Val);
                fclose(dosya);
                
                app.xValue.Text = num2str(matris3(1,4));
                app.yValue.Text = num2str(matris3(2,4));
                app.zValue.Text = num2str(matris3(3,4));

                R = matris3(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris2(1,4),matris2(2,4),matris2(3,4),matris3(1,4),matris3(2,4),matris3(3,4),'R');
            end

            dosyaAdi = 'P-T03.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction6 = str2func(['@(sym3,sym2,sym1) ' sembolikIfade]);
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris3 = symbolicFunction6(sym3Val,sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris3(1,4));
                app.yValue.Text = num2str(matris3(2,4));
                app.zValue.Text = num2str(matris3(3,4));

                R = matris3(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris2(1,4),matris2(2,4),matris2(3,4),matris3(1,4),matris3(2,4),matris3(3,4),'P');
            end

            dosyaAdi = 'S-T03.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                matris3 = fgetl(dosya);
                matris3=str2num(matris);
                fclose(dosya);

                app.xValue.Text = num2str(matris3(1,4));
                app.yValue.Text = num2str(matris3(2,4));
                app.zValue.Text = num2str(matris3(3,4));

                R = matris3(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris2(1,4),matris2(2,4),matris2(3,4),matris3(1,4),matris3(2,4),matris3(3,4),'S');
            end


            dosyaAdi = 'R-T04.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction7 = str2func(['@(sym4,sym3,sym2,sym1) ' sembolikIfade]);
                sym4Val = app.line4Degree.Value;
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris4 = symbolicFunction7(sym4Val,sym3Val,sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris4(1,4));
                app.yValue.Text = num2str(matris4(2,4));
                app.zValue.Text = num2str(matris4(3,4));

                R = matris4(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris3(1,4),matris3(2,4),matris3(3,4),matris4(1,4),matris4(2,4),matris4(3,4),'R');
            end

            dosyaAdi = 'P-T04.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction8 = str2func(['@(sym4,sym3,sym2,sym1) ' sembolikIfade]);
                sym4Val = app.line4Degree.Value;
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris4 = symbolicFunction8(sym4Val,sym3Val,sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris4(1,4));
                app.yValue.Text = num2str(matris4(2,4));
                app.zValue.Text = num2str(matris4(3,4));

                R = matris4(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris3(1,4),matris3(2,4),matris3(3,4),matris4(1,4),matris4(2,4),matris4(3,4),'P');
            end

            dosyaAdi = 'S-T04.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                matris4 = fgetl(dosya);
                matris4=str2num(matris);
                fclose(dosya);

                app.xValue.Text = num2str(matris4(1,4));
                app.yValue.Text = num2str(matris4(2,4));
                app.zValue.Text = num2str(matris4(3,4));

                R = matris4(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris3(1,4),matris3(2,4),matris3(3,4),matris4(1,4),matris4(2,4),matris4(3,4),'S');
            end

            dosyaAdi = 'R-T05.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction9 = str2func(['@(sym5,sym4,sym3,sym2,sym1) ' sembolikIfade]);
                sym5Val = app.line5Degree.Value;
                sym4Val = app.line4Degree.Value;
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris5 = symbolicFunction9(sym5Val,sym4Val,sym3Val,sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris5(1,4));
                app.yValue.Text = num2str(matris5(2,4));
                app.zValue.Text = num2str(matris5(3,4));

                R = matris5(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris4(1,4),matris4(2,4),matris4(3,4),matris5(1,4),matris5(2,4),matris5(3,4),'R');
            end

            dosyaAdi = 'P-T05.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction10 = str2func(['@(sym5,sym4,sym3,sym2,sym1) ' sembolikIfade]);
                sym5Val = app.line5Degree.Value;
                sym4Val = app.line4Degree.Value;
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris5 = symbolicFunction10(sym5Val,sym4Val,sym3Val,sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris5(1,4));
                app.yValue.Text = num2str(matris5(2,4));
                app.zValue.Text = num2str(matris5(3,4));

                R = matris5(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris4(1,4),matris4(2,4),matris4(3,4),matris5(1,4),matris5(2,4),matris5(3,4),'P');
            end

            dosyaAdi = 'S-T05.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                matris5 = fgetl(dosya);
                matris5=str2num(matris);
                fclose(dosya);

                app.xValue.Text = num2str(matris5(1,4));
                app.yValue.Text = num2str(matris5(2,4));
                app.zValue.Text = num2str(matris5(3,4));

                R = matris5(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris4(1,4),matris4(2,4),matris4(3,4),matris5(1,4),matris5(2,4),matris5(3,4),'S');
            end

            dosyaAdi = 'R-T06.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction11 = str2func(['@(sym6,sym5,sym4,sym3,sym2,sym1) ' sembolikIfade]);
                sym6Val = app.line6Degree.Value;
                sym5Val = app.line5Degree.Value;
                sym4Val = app.line4Degree.Value;
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris6 = symbolicFunction11(sym6Val,sym5Val,sym4Val,sym3Val,sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris6(1,4));
                app.yValue.Text = num2str(matris6(2,4));
                app.zValue.Text = num2str(matris6(3,4));

                R = matris6(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris5(1,4),matris5(2,4),matris5(3,4),matris6(1,4),matris6(2,4),matris6(3,4),'R');
            end

            dosyaAdi = 'P-T06.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction12 = str2func(['@(sym6,sym5,sym4,sym3,sym2,sym1) ' sembolikIfade]);
                sym6Val = app.line6Degree.Value;
                sym5Val = app.line5Degree.Value;
                sym4Val = app.line4Degree.Value;
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris6 = symbolicFunction12(sym6Val,sym5Val,sym4Val,sym3Val,sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris6(1,4));
                app.yValue.Text = num2str(matris6(2,4));
                app.zValue.Text = num2str(matris6(3,4));

                R = matris6(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris5(1,4),matris5(2,4),matris5(3,4),matris6(1,4),matris6(2,4),matris6(3,4),'P');
            end

            dosyaAdi = 'S-T06.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                matris6 = fgetl(dosya);
                matris6=str2num(matris);
                fclose(dosya);

                app.xValue.Text = num2str(matris6(1,4));
                app.yValue.Text = num2str(matris6(2,4));
                app.zValue.Text = num2str(matris6(3,4));

                R = matris6(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris5(1,4),matris5(2,4),matris5(3,4),matris6(1,4),matris6(2,4),matris6(3,4),'S');
            end

            dosyaAdi = 'R-T07.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction13 = str2func(['@(sym7,sym6,sym5,sym4,sym3,sym2,sym1) ' sembolikIfade]);
                sym7Val = app.line7Degree.Value;
                sym6Val = app.line6Degree.Value;
                sym5Val = app.line5Degree.Value;
                sym4Val = app.line4Degree.Value;
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris7 = symbolicFunction13(sym7Val,sym6Val,sym5Val,sym4Val,sym3Val,sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris7(1,4));
                app.yValue.Text = num2str(matris7(2,4));
                app.zValue.Text = num2str(matris7(3,4));

                R = matris7(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris6(1,4),matris6(2,4),matris6(3,4),matris7(1,4),matris7(2,4),matris7(3,4),'R');
            end

            dosyaAdi = 'P-T07.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction14 = str2func(['@(sym7,sym6,sym5,sym4,sym3,sym2,sym1) ' sembolikIfade]);
                sym7Val = app.line7Degree.Value;
                sym6Val = app.line6Degree.Value;
                sym5Val = app.line5Degree.Value;
                sym4Val = app.line4Degree.Value;
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris7 = symbolicFunction14(sym7Val,sym6Val,sym5Val,sym4Val,sym3Val,sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris7(1,4));
                app.yValue.Text = num2str(matris7(2,4));
                app.zValue.Text = num2str(matris7(3,4));

                R = matris7(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris6(1,4),matris6(2,4),matris6(3,4),matris7(1,4),matris7(2,4),matris7(3,4),'P');
            end

            dosyaAdi = 'S-T07.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                matris7 = fgetl(dosya);
                matris7=str2num(matris);
                fclose(dosya);

                app.xValue.Text = num2str(matris7(1,4));
                app.yValue.Text = num2str(matris7(2,4));
                app.zValue.Text = num2str(matris7(3,4));

                R = matris7(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris6(1,4),matris6(2,4),matris6(3,4),matris7(1,4),matris7(2,4),matris7(3,4),'S');
            end
            

            dosyaAdi = 'R-T08.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction15 = str2func(['@(sym8,sym7,sym6,sym5,sym4,sym3,sym2,sym1) ' sembolikIfade]);
                sym8Val = app.line8Degree.Value;
                sym7Val = app.line7Degree.Value;
                sym6Val = app.line6Degree.Value;
                sym5Val = app.line5Degree.Value;
                sym4Val = app.line4Degree.Value;
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris8 = symbolicFunction15(sym8Val,sym7Val,sym6Val,sym5Val,sym4Val,sym3Val,sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris8(1,4));
                app.yValue.Text = num2str(matris8(2,4));
                app.zValue.Text = num2str(matris8(3,4));
                

                R = matris8(1:3, 1:3);
                eulerAngles = rotm2eul(R);
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris7(1,4),matris7(2,4),matris7(3,4),matris8(1,4),matris8(2,4),matris8(3,4),'R');
            end

            dosyaAdi = 'P-T08.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                sembolikIfade = fgetl(dosya);
                symbolicFunction16 = str2func(['@(sym8,sym7,sym6,sym5,sym4,sym3,sym2,sym1) ' sembolikIfade]);
                sym8Val = app.line8Degree.Value;
                sym7Val = app.line7Degree.Value;
                sym6Val = app.line6Degree.Value;
                sym5Val = app.line5Degree.Value;
                sym4Val = app.line4Degree.Value;
                sym3Val = app.line3Degree.Value;
                sym2Val = app.line2Degree.Value;
                sym1Val = app.line1Degree.Value;
                matris8 = symbolicFunction16(sym8Val,sym7Val,sym6Val,sym5Val,sym4Val,sym3Val,sym2Val,sym1Val);
                fclose(dosya);

                app.xValue.Text = num2str(matris8(1,4));
                app.yValue.Text = num2str(matris8(2,4));
                app.zValue.Text = num2str(matris8(3,4));

                R = matris8(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1,1));
                app.pitchValue.Text = num2str(eulerAngles(1,2));
                app.yawValue.Text = num2str(eulerAngles(1,3));

                lineDraw(app,matris7(1,4),matris7(2,4),matris7(3,4),matris8(1,4),matris8(2,4),matris8(3,4),'P');
            end

            dosyaAdi = 'S-T08.txt';
            if exist(dosyaAdi, 'file') == 2
                dosya = fopen(dosyaAdi, 'r');
                matris8 = fgetl(dosya);
                matris8=str2num(matris);
                fclose(dosya);

                app.xValue.Text = num2str(matris8(1,4));
                app.yValue.Text = num2str(matris8(2,4));
                app.zValue.Text = num2str(matris8(3,4));

                R = matris8(1:3, 1:3);
                eulerAngles = rotm2eul(R, 'XYZ');
                app.rollValue.Text = num2str(eulerAngles(1));
                app.pitchValue.Text = num2str(eulerAngles(2));
                app.yawValue.Text = num2str(eulerAngles(3));

                lineDraw(app,matris7(1,4),matris7(2,4),matris7(3,4),matris8(1,4),matris8(2,4),matris8(3,4),'S');
            end

            axis(app.UIAxes, 'auto');

            xLim = xlim(app.UIAxes);
            yLim = ylim(app.UIAxes);
            zLim = zlim(app.UIAxes);
            
            maxLim = max([xLim(2), yLim(2), zLim(2)]);
            minLim = min([xLim(1), yLim(1), zLim(1)]);
            
            xlim(app.UIAxes, [minLim,maxLim]);
            ylim(app.UIAxes, [minLim,maxLim]);
            zlim(app.UIAxes, [minLim,maxLim]);

        end
        
        function updateSim(app)
            cla(app.sim_UIAxes);

            %line01
            a = app.sim_a1.Value;
            alfa = app.sim_alp1.Value;
            d = app.sim_d1.Value;
            teta = app.sim_q1.Value + app.sim_line1Degree.Value;
            
            result = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];


            simLineDraw(app,0,0,0,result(1,4),result(2,4),result(3,4));
            
            %line02
            a = app.sim_a2.Value;
            alfa = app.sim_alp2.Value;
            d = app.sim_d2.Value;
            teta = app.sim_q2.Value + app.sim_line2Degree.Value;
            
            resultNew = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

            resultNew = result * resultNew;
            
            simLineDraw(app,result(1,4),result(2,4),result(3,4), resultNew(1,4),resultNew(2,4),resultNew(3,4));

            result = resultNew;

            %line03
            a = app.sim_a3.Value;
            alfa = app.sim_alp3.Value;
            d = app.sim_d3.Value;
            teta = app.sim_q3.Value + app.sim_line3Degree.Value;
            
            resultNew = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

            resultNew = result * resultNew;
            
            simLineDraw(app,result(1,4),result(2,4),result(3,4), resultNew(1,4),resultNew(2,4),resultNew(3,4));

            result = resultNew;

            %line04
            a = app.sim_a4.Value;
            alfa = app.sim_alp4.Value;
            d = app.sim_d4.Value;
            teta = app.sim_q4.Value + app.sim_line4Degree.Value;
            
            resultNew = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

            resultNew = result * resultNew;
            
            simLineDraw(app,result(1,4),result(2,4),result(3,4), resultNew(1,4),resultNew(2,4),resultNew(3,4));

            result = resultNew;

            %line05
            a = app.sim_a5.Value;
            alfa = app.sim_alp5.Value;
            d = app.sim_d5.Value;
            teta = app.sim_q5.Value + app.sim_line5Degree.Value;
            
            resultNew = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

            resultNew = result * resultNew;
            
            simLineDraw(app,result(1,4),result(2,4),result(3,4), resultNew(1,4),resultNew(2,4),resultNew(3,4));

            result = resultNew;

            %line06
            a = app.sim_a6.Value;
            alfa = app.sim_alp6.Value;
            d = app.sim_d6.Value;
            teta = app.sim_q6.Value + app.sim_line6Degree.Value;
            
            resultNew = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

            resultNew = result * resultNew;
            
            simLineDraw(app,result(1,4),result(2,4),result(3,4), resultNew(1,4),resultNew(2,4),resultNew(3,4));

            result = resultNew;
            
            R = result(1:3, 1:3);
            eulerAngles = rotm2eul(R, 'XYZ');
            app.sim_rollValue.Text = num2str(eulerAngles(1,1));
            app.sim_pitchValue.Text = num2str(eulerAngles(1,2));
            app.sim_yawValue.Text = num2str(eulerAngles(1,3));

            app.sim_xValue.Text=num2str(result(1,4));
            app.sim_yValue.Text=num2str(result(2,4));
            app.sim_zValue.Text=num2str(result(3,4));

            axis(app.sim_UIAxes, 'auto');

            xLim = xlim(app.sim_UIAxes);
            yLim = ylim(app.sim_UIAxes);
            zLim = zlim(app.sim_UIAxes);
            
            maxLim = max([xLim(2), yLim(2), zLim(2)]);
            minLim = min([xLim(1), yLim(1), zLim(1)]);
            
            xlim(app.sim_UIAxes, [minLim,maxLim]);
            ylim(app.sim_UIAxes, [minLim,maxLim]);
            zlim(app.sim_UIAxes, [minLim,maxLim]);
        end
        
        function simLineDraw(app, x1, y1, z1, x2, y2, z2)
            hold(app.sim_UIAxes, 'on');
            plot3(app.sim_UIAxes, [x1, x2], [y1, y2], [z1, z2], 'LineWidth', 6);
            scatter3(app.sim_UIAxes, x1, y1, z1, 'Marker', 'o', 'SizeData', 100, 'MarkerFaceColor', 'flat');
            view(app.sim_UIAxes,45,45);
            hold(app.sim_UIAxes, 'off');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: lineType1
        function lineType1ValueChanged(app, event)
            value = app.lineType1.Value;
            if value==1 | value==2 | value==3
                lineOn(app,1);
            else
                lineOff(app,1);
                lineOff(app,2);
                lineOff(app,3);
                lineOff(app,4);
                lineOff(app,5);
                lineOff(app,6);
                lineOff(app,7);
                lineOff(app,8);
            end
        end

        % Value changed function: lineType2
        function lineType2ValueChanged(app, event)
            value = app.lineType2.Value;
            if (value==1 | value==2 | value==3) & (app.lineType1.Value == 1 | app.lineType1.Value == 2 | app.lineType1.Value == 3) 
                lineOn(app,2);
            else
                lineOff(app,2);
                lineOff(app,3);
                lineOff(app,4);
                lineOff(app,5);
                lineOff(app,6);
                lineOff(app,7);
                lineOff(app,8);
            end
        end

        % Value changed function: lineType3
        function lineType3ValueChanged(app, event)
            value = app.lineType3.Value;
            if (value==1 | value==2 | value==3) & (app.lineType2.Value == 1 | app.lineType2.Value == 2 | app.lineType2.Value == 3) 
                lineOn(app,3);
            else
                lineOff(app,3);
                lineOff(app,4);
                lineOff(app,5);
                lineOff(app,6);
                lineOff(app,7);
                lineOff(app,8);
            end
        end

        % Value changed function: lineType4
        function lineType4ValueChanged(app, event)
            value = app.lineType4.Value;
            if (value==1 | value==2 | value==3) & (app.lineType3.Value == 1 | app.lineType3.Value == 2 | app.lineType3.Value == 3) 
                lineOn(app,4);
            else
                lineOff(app,4);
                lineOff(app,5);
                lineOff(app,6);
                lineOff(app,7);
                lineOff(app,8);
            end
        end

        % Value changed function: lineType5
        function lineType5ValueChanged(app, event)
            value = app.lineType5.Value;
            if (value==1 | value==2 | value==3) & (app.lineType4.Value == 1 | app.lineType4.Value == 2 | app.lineType4.Value == 3) 
                lineOn(app,5);
            else
                lineOff(app,5);
                lineOff(app,6);
                lineOff(app,7);
                lineOff(app,8);
            end
        end

        % Value changed function: lineType6
        function lineType6ValueChanged(app, event)
            value = app.lineType6.Value;
            if (value==1 | value==2 | value==3) & (app.lineType5.Value == 1 | app.lineType5.Value == 2 | app.lineType5.Value == 3) 
                lineOn(app,6);
            else
                lineOff(app,6);
                lineOff(app,7);
                lineOff(app,8);
            end
        end

        % Value changed function: lineType7
        function lineType7ValueChanged(app, event)
            value = app.lineType7.Value;
            if (value==1 | value==2 | value==3) & (app.lineType6.Value == 1 | app.lineType6.Value == 2 | app.lineType6.Value == 3) 
                lineOn(app,7);
            else
                lineOff(app,7);
                lineOff(app,8);
            end
        end

        % Value changed function: lineType8
        function lineType8ValueChanged(app, event)
            value = app.lineType8.Value;
            if (value==1 | value==2 | value==3) & (app.lineType7.Value == 1 | app.lineType7.Value == 2 | app.lineType7.Value == 3) 
                lineOn(app,8);
            else
                lineOff(app,8);
            end
        end

        % Value changed function: line1Degree
        function line1DegreeValueChanged(app, event)
            app.line1Slider.Value = app.line1Degree.Value;
            updateGraph(app);
        end

        % Value changed function: line2Degree
        function line2DegreeValueChanged(app, event)
            app.line2Slider.Value = app.line2Degree.Value;  
            updateGraph(app);
        end

        % Value changed function: line3Degree
        function line3DegreeValueChanged(app, event)
            app.line3Slider.Value = app.line3Degree.Value;
            updateGraph(app);
        end

        % Value changed function: line4Degree
        function line4DegreeValueChanged(app, event)
            app.line4Slider.Value = app.line4Degree.Value;
            updateGraph(app);
        end

        % Value changed function: line5Degree
        function line5DegreeValueChanged(app, event)
            app.line5Slider.Value = app.line5Degree.Value;
            updateGraph(app);
        end

        % Value changed function: line6Degree
        function line6DegreeValueChanged(app, event)
            app.line6Slider.Value = app.line6Degree.Value;
            updateGraph(app);
        end

        % Value changed function: line7Degree
        function line7DegreeValueChanged(app, event)
            app.line7Slider.Value = app.line7Degree.Value;
            updateGraph(app);
        end

        % Value changed function: line8Degree
        function line8DegreeValueChanged(app, event)
            app.line8Slider.Value = app.line8Degree.Value;
            updateGraph(app);
        end

        % Value changing function: line1Slider
        function line1SliderValueChanging(app, event)
            app.line1Degree.Value = event.Value;
            updateGraph(app);
        end

        % Value changing function: line2Slider
        function line2SliderValueChanging(app, event)
            app.line2Degree.Value = event.Value;
            updateGraph(app);
        end

        % Value changing function: line3Slider
        function line3SliderValueChanging(app, event)
            app.line3Degree.Value = event.Value;
            updateGraph(app);
        end

        % Value changing function: line4Slider
        function line4SliderValueChanging(app, event)
            app.line4Degree.Value = event.Value;
            updateGraph(app);
        end

        % Value changing function: line5Slider
        function line5SliderValueChanging(app, event)
            app.line5Degree.Value = event.Value;
            updateGraph(app);
        end

        % Value changing function: line6Slider
        function line6SliderValueChanging(app, event)
            app.line6Degree.Value = event.Value;
            updateGraph(app);
        end

        % Value changing function: line7Slider
        function line7SliderValueChanging(app, event)
            app.line7Degree.Value = event.Value;
            updateGraph(app);
        end

        % Value changing function: line8Slider
        function line8SliderValueChanging(app, event)
            app.line8Degree.Value = event.Value;
            updateGraph(app);
        end

        % Button pushed function: hesapla
        function hesaplaButtonPushed(app, event)
            switch app.lineType1.Value
                case 1
                    a = app.a1.Value;
                    alfa = app.alp1.Value;
                    d = app.d1.Value;
                    teta = app.q1.Value;
                    syms sym1;
                    teta = teta + sym1;

                    matris1 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

                    matris1 = vpa(matris1);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'R-T01.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris1));
                    fclose(fid);

                    result = matris1;

                case 2
                    a = app.a1.Value;
                    alfa = app.alp1.Value;
                    d = app.d1.Value;
                    teta = app.q1.Value;
                    syms sym1;
                    d = d + sym1;

                    matris1 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris1 = vpa(matris1);
                    
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'P-T01.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris1));
                    fclose(fid);

                    result = matris1;

                case 3
                    a = app.a1.Value;
                    alfa = app.alp1.Value;
                    d = app.d1.Value;
                    teta = app.q1.Value;

                    matris1 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris1 = vpa(matris1);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'S-T01.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris1));
                    fclose(fid);

                    result = matris1;
                    

            end

            switch app.lineType2.Value
                case 1
                    a = app.a2.Value;
                    alfa = app.alp2.Value;
                    d = app.d2.Value;
                    teta = app.q2.Value;
                    syms sym2;
                    teta = teta + sym2;

                    matris2 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

                    matris2 = vpa(matris2);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'R-T12.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris2));
                    fclose(fid);

                    result = result * matris2;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'R-T02.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 2
                    a = app.a2.Value;
                    alfa = app.alp2.Value;
                    d = app.d2.Value;
                    teta = app.q2.Value;
                    syms sym2;
                    d = d + sym2;

                    matris2 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris2 = vpa(matris2);
                    
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T12.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris2));
                    fclose(fid);

                    result = result * matris2;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'P-T02.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 3
                    a = app.a2.Value;
                    alfa = app.alp2.Value;
                    d = app.d2.Value;
                    teta = app.q2.Value;

                    matris2 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris2 = vpa(matris2);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T12.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris2));
                    fclose(fid);

                    result = result * matris2;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'S-T02.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

            end


             switch app.lineType3.Value
                case 1
                    a = app.a3.Value;
                    alfa = app.alp3.Value;
                    d = app.d3.Value;
                    teta = app.q3.Value;
                    syms sym3;
                    teta = teta + sym3;

                    matris3 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

                    matris3 = vpa(matris3);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T23.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris3));
                    fclose(fid);

                    result = result * matris3;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'R-T03.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 2
                    a = app.a3.Value;
                    alfa = app.alp3.Value;
                    d = app.d3.Value;
                    teta = app.q3.Value;
                    syms sym3;
                    d = d + sym3;

                    matris3 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris3 = vpa(matris3);
                    
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T23.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris3));
                    fclose(fid);

                    result = result * matris3;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'P-T03.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 3
                    a = app.a3.Value;
                    alfa = app.alp3.Value;
                    d = app.d3.Value;
                    teta = app.q3.Value;

                    matris3 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris3 = vpa(matris3);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T23.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris3));
                    fclose(fid);

                    result = result * matris3;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'S-T03.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

             end

             switch app.lineType4.Value
                case 1
                    a = app.a4.Value;
                    alfa = app.alp4.Value;
                    d = app.d4.Value;
                    teta = app.q4.Value;
                    syms sym4;
                    teta = teta + sym4;

                    matris4 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

                    matris4 = vpa(matris4);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T34.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris4));
                    fclose(fid);

                    result = result * matris4;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'R-T04.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 2
                    a = app.a4.Value;
                    alfa = app.alp4.Value;
                    d = app.d4.Value;
                    teta = app.q4.Value;
                    syms sym4;
                    d = d + sym4;

                    matris4 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris4 = vpa(matris4);
                    
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T34.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris4));
                    fclose(fid);

                    result = result * matris4;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'P-T04.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 3
                    a = app.a4.Value;
                    alfa = app.alp4.Value;
                    d = app.d4.Value;
                    teta = app.q4.Value;

                    matris4 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris4 = vpa(matris4);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T34.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris4));
                    fclose(fid);

                    result = result * matris4;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'S-T04.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

             end

             switch app.lineType5.Value
                case 1
                    a = app.a5.Value;
                    alfa = app.alp5.Value;
                    d = app.d5.Value;
                    teta = app.q5.Value;
                    syms sym5;
                    teta = teta + sym5;

                    matris5 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

                    matris5 = vpa(matris5);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T45.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris5));
                    fclose(fid);

                    result = result * matris5;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'R-T05.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 2
                    a = app.a5.Value;
                    alfa = app.alp5.Value;
                    d = app.d5.Value;
                    teta = app.q5.Value;
                    syms sym5;
                    d = d + sym5;

                    matris5 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris5 = vpa(matris5);
                    
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T45.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris5));
                    fclose(fid);

                    result = result * matris5;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'P-T05.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 3
                    a = app.a5.Value;
                    alfa = app.alp5.Value;
                    d = app.d5.Value;
                    teta = app.q5.Value;

                    matris5 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris5 = vpa(matris5);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T45.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris5));
                    fclose(fid);

                    result = result * matris5;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'S-T05.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

             end

             switch app.lineType6.Value
                case 1
                    a = app.a6.Value;
                    alfa = app.alp6.Value;
                    d = app.d6.Value;
                    teta = app.q6.Value;
                    syms sym6;
                    teta = teta + sym6;

                    matris6 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

                    matris6 = vpa(matris6);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T56.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris6));
                    fclose(fid);

                    result = result * matris6;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'R-T06.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 2
                    a = app.a6.Value;
                    alfa = app.alp6.Value;
                    d = app.d6.Value;
                    teta = app.q6.Value;
                    syms sym6;
                    d = d + sym6;

                    matris6 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris6 = vpa(matris6);
                    
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T56.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris6));
                    fclose(fid);

                    result = result * matris6;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'P-T06.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 3
                    a = app.a6.Value;
                    alfa = app.alp6.Value;
                    d = app.d6.Value;
                    teta = app.q6.Value;

                    matris6 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris6 = vpa(matris6);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T56.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris6));
                    fclose(fid);

                    result = result * matris6;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'S-T06.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

             end

             switch app.lineType7.Value
                case 1
                    a = app.a7.Value;
                    alfa = app.alp7.Value;
                    d = app.d7.Value;
                    teta = app.q7.Value;
                    syms sym7;
                    teta = teta + sym7;

                    matris7 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

                    matris7 = vpa(matris7);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T67.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris7));
                    fclose(fid);

                    result = result * matris7;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'R-T07.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 2
                    a = app.a7.Value;
                    alfa = app.alp7.Value;
                    d = app.d7.Value;
                    teta = app.q7.Value;
                    syms sym7;
                    d = d + sym7;

                    matris7 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris7 = vpa(matris7);
                    
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T67.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris7));
                    fclose(fid);

                    result = result * matris7;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'P-T07.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 3
                    a = app.a7.Value;
                    alfa = app.alp7.Value;
                    d = app.d7.Value;
                    teta = app.q7.Value;

                    matris7 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris7 = vpa(matris7);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T67.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris7));
                    fclose(fid);

                    result = result * matris7;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'S-T07.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

             end

             switch app.lineType8.Value
                case 1
                    a = app.a8.Value;
                    alfa = app.alp8.Value;
                    d = app.d8.Value;
                    teta = app.q8.Value;
                    syms sym8;
                    teta = teta + sym8;

                    matris8 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];

                    matris8 = vpa(matris8);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T78.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris8));
                    fclose(fid);

                    result = result * matris8;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'R-T08.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 2
                    a = app.a8.Value;
                    alfa = app.alp8.Value;
                    d = app.d8.Value;
                    teta = app.q8.Value;
                    syms sym8;
                    d = d + sym8;

                    matris8 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris8 = vpa(matris8);
                    
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T78.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris8));
                    fclose(fid);

                    result = result * matris8;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'P-T08.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

                case 3
                    a = app.a8.Value;
                    alfa = app.alp8.Value;
                    d = app.d8.Value;
                    teta = app.q8.Value;

                    matris8 = [
                                cosd(teta) -sind(teta)*cosd(alfa) sind(teta)*sind(alfa) a*cosd(teta);
                                sind(teta) cosd(teta)*cosd(alfa) -cosd(teta)*sind(alfa) a*sind(teta);
                                0 sind(alfa) cosd(alfa) d;
                                0 0 0 1
                    ];
            
                    matris8 = vpa(matris8);

                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'T78.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(matris8));
                    fclose(fid);

                    result = result * matris8;
                    guiDosyaYolu = fileparts(mfilename('fullpath'));
                    dosyaAdi = fullfile(guiDosyaYolu, 'S-T08.txt');
                    fid = fopen(dosyaAdi, 'w');
                    fprintf(fid, '%s\n', char(result));
                    fclose(fid);

             end

             disp('HESAPLANDI');
        
        end

        % Button pushed function: cizdir
        function cizdirButtonPushed(app, event)
            updateGraph(app);            
        end

        % Button pushed function: sim_calistir
        function sim_calistirButtonPushed(app, event)
            app.sim_line1Slider.Enable = "on";
            app.sim_line2Slider.Enable = "on";
            app.sim_line3Slider.Enable = "on";
            app.sim_line4Slider.Enable = "on";
            app.sim_line5Slider.Enable = "on";
            app.sim_line6Slider.Enable = "on";

            app.sim_line1Degree.Enable = "on";
            app.sim_line2Degree.Enable = "on";
            app.sim_line3Degree.Enable = "on";
            app.sim_line4Degree.Enable = "on";
            app.sim_line5Degree.Enable = "on";
            app.sim_line6Degree.Enable = "on";

            app.sim_line1Degree.Editable = "on";
            app.sim_line2Degree.Editable = "on";
            app.sim_line3Degree.Editable = "on";
            app.sim_line4Degree.Editable = "on";
            app.sim_line5Degree.Editable = "on";
            app.sim_line6Degree.Editable = "on";

            updateSim(app);

            open_system('kawasakiRobot.slx');
            sim('kawasakiRobot.slx');
            close_system('kawasakiRobot.slx', 0);

            
        end

        % Button pushed function: sim_durdur
        function sim_durdurButtonPushed(app, event)
            app.sim_line1Slider.Enable = "off";
            app.sim_line2Slider.Enable = "off";
            app.sim_line3Slider.Enable = "off";
            app.sim_line4Slider.Enable = "off";
            app.sim_line5Slider.Enable = "off";
            app.sim_line6Slider.Enable = "off";

            app.sim_line1Degree.Enable = "off";
            app.sim_line2Degree.Enable = "off";
            app.sim_line3Degree.Enable = "off";
            app.sim_line4Degree.Enable = "off";
            app.sim_line5Degree.Enable = "off";
            app.sim_line6Degree.Enable = "off";

            app.sim_line1Degree.Editable = "off";
            app.sim_line2Degree.Editable = "off";
            app.sim_line3Degree.Editable = "off";
            app.sim_line4Degree.Editable = "off";
            app.sim_line5Degree.Editable = "off";
            app.sim_line6Degree.Editable = "off";

            app.sim_line1Slider.Value= 0;
            app.sim_line2Slider.Value = 0;
            app.sim_line3Slider.Value = 0;
            app.sim_line4Slider.Value = 0;
            app.sim_line5Slider.Value = 0;
            app.sim_line6Slider.Value = 0;

            app.sim_line1Degree.Value = 0;
            app.sim_line2Degree.Value = 0;
            app.sim_line3Degree.Value = 0;
            app.sim_line4Degree.Value = 0;
            app.sim_line5Degree.Value = 0;
            app.sim_line6Degree.Value = 0;

            cla(app.sim_UIAxes);

            app.sim_xValue.Text = '0';
            app.sim_yValue.Text = '0';
            app.sim_zValue.Text = '0';

            app.sim_rollValue.Text = '0';
            app.sim_pitchValue.Text = '0';
            app.sim_yawValue.Text = '0';
            
            blockname = 'kawasakiRobot/quitBlock';
            set_param(blockname,'Value','1');


        end

        % Value changed function: sim_line1Degree
        function sim_line1DegreeValueChanged(app, event)
            app.sim_line1Slider.Value = app.sim_line1Degree.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j1Degree';
            set_param(blockname,'Value',num2str(app.sim_line1Degree.Value));
        end

        % Value changed function: sim_line2Degree
        function sim_line2DegreeValueChanged(app, event)
            app.sim_line2Slider.Value = app.sim_line2Degree.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j2Degree';
            set_param(blockname,'Value',num2str(app.sim_line2Degree.Value));
        end

        % Value changed function: sim_line3Degree
        function sim_line3DegreeValueChanged(app, event)
            app.sim_line3Slider.Value = app.sim_line3Degree.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j3Degree';
            set_param(blockname,'Value',num2str(app.sim_line3Degree.Value));
        end

        % Value changed function: sim_line4Degree
        function sim_line4DegreeValueChanged(app, event)
            app.sim_line4Slider.Value = app.sim_line4Degree.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j4Degree';
            set_param(blockname,'Value',num2str(app.sim_line4Degree.Value));
        end

        % Value changed function: sim_line5Degree
        function sim_line5DegreeValueChanged(app, event)
            app.sim_line5Slider.Value = app.sim_line5Degree.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j5Degree';
            set_param(blockname,'Value',num2str(app.sim_line5Degree.Value));
        end

        % Value changed function: sim_line6Degree
        function sim_line6DegreeValueChanged(app, event)
            app.sim_line6Slider.Value = app.sim_line6Degree.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j6Degree';
            set_param(blockname,'Value',num2str(app.sim_line6Degree.Value));
        end

        % Value changing function: sim_line1Slider
        function sim_line1SliderValueChanging(app, event)
            app.sim_line1Degree.Value = event.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j1Degree';
            set_param(blockname,'Value',num2str(event.Value));
        end

        % Value changing function: sim_line2Slider
        function sim_line2SliderValueChanging(app, event)
            app.sim_line2Degree.Value = event.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j2Degree';
            set_param(blockname,'Value',num2str(event.Value));
        end

        % Value changing function: sim_line3Slider
        function sim_line3SliderValueChanging(app, event)
            app.sim_line3Degree.Value = event.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j3Degree';
            set_param(blockname,'Value',num2str(event.Value));
        end

        % Value changing function: sim_line4Slider
        function sim_line4SliderValueChanging(app, event)
            app.sim_line4Degree.Value = event.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j4Degree';
            set_param(blockname,'Value',num2str(event.Value));
        end

        % Value changing function: sim_line5Slider
        function sim_line5SliderValueChanging(app, event)
            app.sim_line5Degree.Value = event.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j5Degree';
            set_param(blockname,'Value',num2str(event.Value));
        end

        % Value changing function: sim_line6Slider
        function sim_line6SliderValueChanging(app, event)
            app.sim_line6Degree.Value = event.Value;
            updateSim(app);
            blockname = 'kawasakiRobot/j6Degree';
            set_param(blockname,'Value',num2str(event.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GenelTab = uitab(app.TabGroup);
            app.GenelTab.Title = 'Genel';
            app.GenelGrid = uigridlayout(app.GenelTab);
            app.UIAxes = uiaxes(app.GenelGrid);
            title(app.UIAxes, '3B Çizgisel Robot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.ZLimitMethod = 'tight';
            app.TableTitle = uilabel(app.GenelGrid);
            app.TableTitle.Text = 'DH';
            app.aTitle = uilabel(app.GenelGrid);
            app.aTitle.Text = 'a';
            app.alfaDegTitle = uilabel(app.GenelGrid);
            app.alfaDegTitle.Text = 'alfa (Deg)';
            app.dTitle = uilabel(app.GenelGrid);
            app.dTitle.Text = 'd';
            app.tetaDegTitle = uilabel(app.GenelGrid);
            app.tetaDegTitle.Text = 'teta (Deg)';
            app.EklemTipiTitle = uilabel(app.GenelGrid);
            app.EklemTipiTitle.Text = 'Eklem Tipi';
            app.EklemDeskeniTitle = uilabel(app.GenelGrid);
            app.EklemDeskeniTitle.Text = 'Eklem Değişkeni';
            app.line1Title = uilabel(app.GenelGrid);
            app.line1Title.Text = '1';
            app.line2Title = uilabel(app.GenelGrid);
            app.line2Title.Text = '2';
            app.line3Title = uilabel(app.GenelGrid);
            app.line3Title.Text = '3';
            app.line4Title = uilabel(app.GenelGrid);
            app.line4Title.Text = '4';
            app.line5Title = uilabel(app.GenelGrid);
            app.line5Title.Text = '5';
            app.line6Title = uilabel(app.GenelGrid);
            app.line6Title.Text = '6';
            app.line7Title = uilabel(app.GenelGrid);
            app.line7Title.Text = '7';
            app.line8Title = uilabel(app.GenelGrid);
            app.line8Title.Text = '8';
            app.a1 = uieditfield(app.GenelGrid, 'numeric');
            app.a1.Editable = 'off';
            app.a1.Enable = 'off';
            app.a1.Placeholder = '0';
            app.alp1 = uieditfield(app.GenelGrid, 'numeric');
            app.alp1.Editable = 'off';
            app.alp1.Enable = 'off';
            app.alp1.Placeholder = '0';
            app.d1 = uieditfield(app.GenelGrid, 'numeric');
            app.d1.Editable = 'off';
            app.d1.Enable = 'off';
            app.d1.Placeholder = '0';
            app.q1 = uieditfield(app.GenelGrid, 'numeric');
            app.q1.Editable = 'off';
            app.q1.Enable = 'off';
            app.q1.Placeholder = '0';
            app.a2 = uieditfield(app.GenelGrid, 'numeric');
            app.a2.Editable = 'off';
            app.a2.Enable = 'off';
            app.a2.Placeholder = '0';
            app.alp2 = uieditfield(app.GenelGrid, 'numeric');
            app.alp2.Editable = 'off';
            app.alp2.Enable = 'off';
            app.alp2.Placeholder = '0';
            app.d2 = uieditfield(app.GenelGrid, 'numeric');
            app.d2.Editable = 'off';
            app.d2.Enable = 'off';
            app.d2.Placeholder = '0';
            app.q2 = uieditfield(app.GenelGrid, 'numeric');
            app.q2.Editable = 'off';
            app.q2.Enable = 'off';
            app.q2.Placeholder = '0';
            app.a3 = uieditfield(app.GenelGrid, 'numeric');
            app.a3.Editable = 'off';
            app.a3.Enable = 'off';
            app.a3.Placeholder = '0';
            app.alp3 = uieditfield(app.GenelGrid, 'numeric');
            app.alp3.Editable = 'off';
            app.alp3.Enable = 'off';
            app.alp3.Placeholder = '0';
            app.d3 = uieditfield(app.GenelGrid, 'numeric');
            app.d3.Editable = 'off';
            app.d3.Enable = 'off';
            app.d3.Placeholder = '0';
            app.q3 = uieditfield(app.GenelGrid, 'numeric');
            app.q3.Editable = 'off';
            app.q3.Enable = 'off';
            app.q3.Placeholder = '0';
            app.a4 = uieditfield(app.GenelGrid, 'numeric');
            app.a4.Editable = 'off';
            app.a4.Enable = 'off';
            app.a4.Placeholder = '0';
            app.alp4 = uieditfield(app.GenelGrid, 'numeric');
            app.alp4.Editable = 'off';
            app.alp4.Enable = 'off';
            app.alp4.Placeholder = '0';
            app.d4 = uieditfield(app.GenelGrid, 'numeric');
            app.d4.Editable = 'off';
            app.d4.Enable = 'off';
            app.d4.Placeholder = '0';
            app.q4 = uieditfield(app.GenelGrid, 'numeric');
            app.q4.Editable = 'off';
            app.q4.Enable = 'off';
            app.q4.Placeholder = '0';
            app.a5 = uieditfield(app.GenelGrid, 'numeric');
            app.a5.Editable = 'off';
            app.a5.Enable = 'off';
            app.a5.Placeholder = '0';
            app.alp5 = uieditfield(app.GenelGrid, 'numeric');
            app.alp5.Editable = 'off';
            app.alp5.Enable = 'off';
            app.alp5.Placeholder = '0';
            app.d5 = uieditfield(app.GenelGrid, 'numeric');
            app.d5.Editable = 'off';
            app.d5.Enable = 'off';
            app.d5.Placeholder = '0';
            app.q5 = uieditfield(app.GenelGrid, 'numeric');
            app.q5.Editable = 'off';
            app.q5.Enable = 'off';
            app.q5.Placeholder = '0';
            app.a6 = uieditfield(app.GenelGrid, 'numeric');
            app.a6.Editable = 'off';
            app.a6.Enable = 'off';
            app.a6.Placeholder = '0';
            app.alp6 = uieditfield(app.GenelGrid, 'numeric');
            app.alp6.Editable = 'off';
            app.alp6.Enable = 'off';
            app.alp6.Placeholder = '0';
            app.d6 = uieditfield(app.GenelGrid, 'numeric');
            app.d6.Editable = 'off';
            app.d6.Enable = 'off';
            app.d6.Placeholder = '0';
            app.q6 = uieditfield(app.GenelGrid, 'numeric');
            app.q6.Editable = 'off';
            app.q6.Enable = 'off';
            app.q6.Placeholder = '0';
            app.a7 = uieditfield(app.GenelGrid, 'numeric');
            app.a7.Editable = 'off';
            app.a7.Enable = 'off';
            app.a7.Placeholder = '0';
            app.alp7 = uieditfield(app.GenelGrid, 'numeric');
            app.alp7.Editable = 'off';
            app.alp7.Enable = 'off';
            app.alp7.Placeholder = '0';
            app.d7 = uieditfield(app.GenelGrid, 'numeric');
            app.d7.Editable = 'off';
            app.d7.Enable = 'off';
            app.d7.Placeholder = '0';
            app.q7 = uieditfield(app.GenelGrid, 'numeric');
            app.q7.Editable = 'off';
            app.q7.Enable = 'off';
            app.q7.Placeholder = '0';
            app.a8 = uieditfield(app.GenelGrid, 'numeric');
            app.a8.Editable = 'off';
            app.a8.Enable = 'off';
            app.a8.Placeholder = '0';
            app.alp8 = uieditfield(app.GenelGrid, 'numeric');
            app.alp8.Editable = 'off';
            app.alp8.Enable = 'off';
            app.alp8.Placeholder = '0';
            app.d8 = uieditfield(app.GenelGrid, 'numeric');
            app.d8.Editable = 'off';
            app.d8.Enable = 'off';
            app.d8.Placeholder = '0';
            app.q8 = uieditfield(app.GenelGrid, 'numeric');
            app.q8.Editable = 'off';
            app.q8.Enable = 'off';
            app.q8.Placeholder = '0';
            app.lineType1 = uidropdown(app.GenelGrid);
            app.lineType1.Items = {'Kapalı', 'Döner(R)', 'Kayar(P)', 'Sabit'};
            app.lineType1.ItemsData = [0 1 2 3];
            app.lineType1.ValueChangedFcn = createCallbackFcn(app, @lineType1ValueChanged, true);
            app.lineType1.Value = 0;
            app.lineType2 = uidropdown(app.GenelGrid);
            app.lineType2.Items = {'Kapalı', 'Döner(R)', 'Kayar(P)', 'Sabit'};
            app.lineType2.ItemsData = [0 1 2 3];
            app.lineType2.ValueChangedFcn = createCallbackFcn(app, @lineType2ValueChanged, true);
            app.lineType2.Value = 0;
            app.lineType3 = uidropdown(app.GenelGrid);
            app.lineType3.Items = {'Kapalı', 'Döner(R)', 'Kayar(P)', 'Sabit'};
            app.lineType3.ItemsData = [0 1 2 3];
            app.lineType3.ValueChangedFcn = createCallbackFcn(app, @lineType3ValueChanged, true);
            app.lineType3.Value = 0;
            app.lineType4 = uidropdown(app.GenelGrid);
            app.lineType4.Items = {'Kapalı', 'Döner(R)', 'Kayar(P)', 'Sabit'};
            app.lineType4.ItemsData = [0 1 2 3];
            app.lineType4.ValueChangedFcn = createCallbackFcn(app, @lineType4ValueChanged, true);
            app.lineType4.Value = 0;
            app.lineType5 = uidropdown(app.GenelGrid);
            app.lineType5.Items = {'Kapalı', 'Döner(R)', 'Kayar(P)', 'Sabit'};
            app.lineType5.ItemsData = [0 1 2 3];
            app.lineType5.ValueChangedFcn = createCallbackFcn(app, @lineType5ValueChanged, true);
            app.lineType5.Value = 0;
            app.lineType6 = uidropdown(app.GenelGrid);
            app.lineType6.Items = {'Kapalı', 'Döner(R)', 'Kayar(P)', 'Sabit'};
            app.lineType6.ItemsData = [0 1 2 3];
            app.lineType6.ValueChangedFcn = createCallbackFcn(app, @lineType6ValueChanged, true);
            app.lineType6.Value = 0;
            app.lineType7 = uidropdown(app.GenelGrid);
            app.lineType7.Items = {'Kapalı', 'Döner(R)', 'Kayar(P)', 'Sabit'};
            app.lineType7.ItemsData = [0 1 2 3];
            app.lineType7.ValueChangedFcn = createCallbackFcn(app, @lineType7ValueChanged, true);
            app.lineType7.Value = 0;
            app.lineType8 = uidropdown(app.GenelGrid);
            app.lineType8.Items = {'Kapalı', 'Döner(R)', 'Kayar(P)', 'Sabit'};
            app.lineType8.ItemsData = [0 1 2 3];
            app.lineType8.ValueChangedFcn = createCallbackFcn(app, @lineType8ValueChanged, true);
            app.lineType8.Value = 0;
            app.line1Slider = uislider(app.GenelGrid);
            app.line1Slider.Limits = [-180 180];
            app.line1Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line1Slider.ValueChangingFcn = createCallbackFcn(app, @line1SliderValueChanging, true);
            app.line1Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line1Slider.Enable = 'off';
            app.line2Slider = uislider(app.GenelGrid);
            app.line2Slider.Limits = [-180 180];
            app.line2Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line2Slider.ValueChangingFcn = createCallbackFcn(app, @line2SliderValueChanging, true);
            app.line2Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line2Slider.Enable = 'off';
            app.line3Slider = uislider(app.GenelGrid);
            app.line3Slider.Limits = [-180 180];
            app.line3Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line3Slider.ValueChangingFcn = createCallbackFcn(app, @line3SliderValueChanging, true);
            app.line3Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line3Slider.Enable = 'off';
            app.line4Slider = uislider(app.GenelGrid);
            app.line4Slider.Limits = [-180 180];
            app.line4Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line4Slider.ValueChangingFcn = createCallbackFcn(app, @line4SliderValueChanging, true);
            app.line4Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line4Slider.Enable = 'off';
            app.line5Slider = uislider(app.GenelGrid);
            app.line5Slider.Limits = [-180 180];
            app.line5Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line5Slider.ValueChangingFcn = createCallbackFcn(app, @line5SliderValueChanging, true);
            app.line5Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line5Slider.Enable = 'off';
            app.line6Slider = uislider(app.GenelGrid);
            app.line6Slider.Limits = [-180 180];
            app.line6Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line6Slider.ValueChangingFcn = createCallbackFcn(app, @line6SliderValueChanging, true);
            app.line6Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line6Slider.Enable = 'off';
            app.line7Slider = uislider(app.GenelGrid);
            app.line7Slider.Limits = [-180 180];
            app.line7Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line7Slider.ValueChangingFcn = createCallbackFcn(app, @line7SliderValueChanging, true);
            app.line7Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line7Slider.Enable = 'off';
            app.line8Slider = uislider(app.GenelGrid);
            app.line8Slider.Limits = [-180 180];
            app.line8Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line8Slider.ValueChangingFcn = createCallbackFcn(app, @line8SliderValueChanging, true);
            app.line8Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.line8Slider.Enable = 'off';
            app.line1Degree = uieditfield(app.GenelGrid, 'numeric');
            app.line1Degree.Limits = [-180 180];
            app.line1Degree.ValueDisplayFormat = '%.2f';
            app.line1Degree.ValueChangedFcn = createCallbackFcn(app, @line1DegreeValueChanged, true);
            app.line1Degree.Editable = 'off';
            app.line1Degree.Enable = 'off';
            app.line2Degree = uieditfield(app.GenelGrid, 'numeric');
            app.line2Degree.Limits = [-180 180];
            app.line2Degree.ValueDisplayFormat = '%.2f';
            app.line2Degree.ValueChangedFcn = createCallbackFcn(app, @line2DegreeValueChanged, true);
            app.line2Degree.Editable = 'off';
            app.line2Degree.Enable = 'off';
            app.line3Degree = uieditfield(app.GenelGrid, 'numeric');
            app.line3Degree.Limits = [-180 180];
            app.line3Degree.ValueDisplayFormat = '%.2f';
            app.line3Degree.ValueChangedFcn = createCallbackFcn(app, @line3DegreeValueChanged, true);
            app.line3Degree.Editable = 'off';
            app.line3Degree.Enable = 'off';
            app.line4Degree = uieditfield(app.GenelGrid, 'numeric');
            app.line4Degree.Limits = [-180 180];
            app.line4Degree.ValueDisplayFormat = '%.2f';
            app.line4Degree.ValueChangedFcn = createCallbackFcn(app, @line4DegreeValueChanged, true);
            app.line4Degree.Editable = 'off';
            app.line4Degree.Enable = 'off';
            app.line5Degree = uieditfield(app.GenelGrid, 'numeric');
            app.line5Degree.Limits = [-180 180];
            app.line5Degree.ValueDisplayFormat = '%.2f';
            app.line5Degree.ValueChangedFcn = createCallbackFcn(app, @line5DegreeValueChanged, true);
            app.line5Degree.Editable = 'off';
            app.line5Degree.Enable = 'off';
            app.line6Degree = uieditfield(app.GenelGrid, 'numeric');
            app.line6Degree.Limits = [-180 180];
            app.line6Degree.ValueDisplayFormat = '%.2f';
            app.line6Degree.ValueChangedFcn = createCallbackFcn(app, @line6DegreeValueChanged, true);
            app.line6Degree.Editable = 'off';
            app.line6Degree.Enable = 'off';
            app.line7Degree = uieditfield(app.GenelGrid, 'numeric');
            app.line7Degree.Limits = [-180 180];
            app.line7Degree.ValueDisplayFormat = '%.2f';
            app.line7Degree.ValueChangedFcn = createCallbackFcn(app, @line7DegreeValueChanged, true);
            app.line7Degree.Editable = 'off';
            app.line7Degree.Enable = 'off';
            app.line8Degree = uieditfield(app.GenelGrid, 'numeric');
            app.line8Degree.Limits = [-180 180];
            app.line8Degree.ValueDisplayFormat = '%.2f';
            app.line8Degree.ValueChangedFcn = createCallbackFcn(app, @line8DegreeValueChanged, true);
            app.line8Degree.Editable = 'off';
            app.line8Degree.Enable = 'off';
            app.hesapla = uibutton(app.GenelGrid, 'push');
            app.hesapla.ButtonPushedFcn = createCallbackFcn(app, @hesaplaButtonPushed, true);
            app.hesapla.Text = 'HESAPLA';
            app.cizdir = uibutton(app.GenelGrid, 'push');
            app.cizdir.ButtonPushedFcn = createCallbackFcn(app, @cizdirButtonPushed, true);
            app.cizdir.Enable = 'off';
            app.cizdir.Text = 'ÇİZDİR';
            app.xTitle = uilabel(app.GenelGrid);
            app.xTitle.Text = 'X';
            app.yTitle = uilabel(app.GenelGrid);
            app.yTitle.Text = 'Y';
            app.zTitle = uilabel(app.GenelGrid);
            app.zTitle.Text = 'Z';
            app.xValue = uilabel(app.GenelGrid);
            app.xValue.Text = '0';
            app.yValue = uilabel(app.GenelGrid);
            app.yValue.Text = '0';
            app.zValue = uilabel(app.GenelGrid);
            app.zValue.Text = '0';
            app.yawTitle = uilabel(app.GenelGrid);
            app.yawTitle.Text = 'Yaw';
            app.pitchTitle = uilabel(app.GenelGrid);
            app.pitchTitle.Text = 'Pitch';
            app.rollTitle = uilabel(app.GenelGrid);
            app.rollTitle.Text = 'Roll';
            app.yawValue = uilabel(app.GenelGrid);
            app.yawValue.Text = '0';
            app.pitchValue = uilabel(app.GenelGrid);
            app.pitchValue.Text = '0';
            app.rollValue = uilabel(app.GenelGrid);
            app.rollValue.Text = '0';
            app.logo = uiimage(app.GenelGrid);
            app.logo.ImageSource = fullfile(pathToMLAPP, 'logorgb.png');
            app.dersTitle = uilabel(app.GenelGrid);
            app.dersTitle.Text = '2023-2024 Robotik ve Otomasyon Sistemleri';
            app.bolumTitle = uilabel(app.GenelGrid);
            app.bolumTitle.Text = 'Mekatronik Mühendisliği Bölümü';
            app.SimulasyonTab = uitab(app.TabGroup);
            app.SimulasyonTab.Title = 'Simulasyon';
            app.simGrid = uigridlayout(app.SimulasyonTab);
            app.sim_UIAxes = uiaxes(app.simGrid);
            title(app.sim_UIAxes, '3B Çizgisel Robot')
            xlabel(app.sim_UIAxes, 'X')
            ylabel(app.sim_UIAxes, 'Y')
            zlabel(app.sim_UIAxes, 'Z')
            app.simTableTitle = uilabel(app.simGrid);
            app.simTableTitle.Text = 'DH';
            app.simATitle = uilabel(app.simGrid);
            app.simATitle.Text = 'a';
            app.simAlfaDegTitle = uilabel(app.simGrid);
            app.simAlfaDegTitle.Text = 'alfa (Deg)';
            app.simDTitle = uilabel(app.simGrid);
            app.simDTitle.Text = 'd';
            app.simTetaDegTitle = uilabel(app.simGrid);
            app.simTetaDegTitle.Text = 'teta (Deg)';
            app.simDegiskenTitle = uilabel(app.simGrid);
            app.simDegiskenTitle.Text = 'Eklem Değişkeni';
            app.simLine1Title = uilabel(app.simGrid);
            app.simLine1Title.Text = '1';
            app.simLine2Title = uilabel(app.simGrid);
            app.simLine2Title.Text = '2';
            app.simLine3Title = uilabel(app.simGrid);
            app.simLine3Title.Text = '3';
            app.simLine4Title = uilabel(app.simGrid);
            app.simLine4Title.Text = '4';
            app.simLine5Title = uilabel(app.simGrid);
            app.simLine5Title.Text = '5';
            app.simLine6Title = uilabel(app.simGrid);
            app.simLine6Title.Text = '6';
            app.sim_a1 = uieditfield(app.simGrid, 'numeric');
            app.sim_a1.Editable = 'off';
            app.sim_a1.Placeholder = '0';
            app.sim_alp1 = uieditfield(app.simGrid, 'numeric');
            app.sim_alp1.Editable = 'off';
            app.sim_alp1.Placeholder = '0';
            app.sim_d1 = uieditfield(app.simGrid, 'numeric');
            app.sim_d1.Editable = 'off';
            app.sim_d1.Placeholder = '0';
            app.sim_d1.Value = 430;
            app.sim_q1 = uieditfield(app.simGrid, 'numeric');
            app.sim_q1.Editable = 'off';
            app.sim_q1.Placeholder = '0';
            app.sim_a2 = uieditfield(app.simGrid, 'numeric');
            app.sim_a2.Editable = 'off';
            app.sim_a2.Placeholder = '0';
            app.sim_a2.Value = -165;
            app.sim_alp2 = uieditfield(app.simGrid, 'numeric');
            app.sim_alp2.Editable = 'off';
            app.sim_alp2.Placeholder = '0';
            app.sim_d2 = uieditfield(app.simGrid, 'numeric');
            app.sim_d2.Editable = 'off';
            app.sim_d2.Placeholder = '0';
            app.sim_q2 = uieditfield(app.simGrid, 'numeric');
            app.sim_q2.Editable = 'off';
            app.sim_q2.Placeholder = '0';
            app.sim_q2.Value = 90;
            app.sim_a3 = uieditfield(app.simGrid, 'numeric');
            app.sim_a3.Editable = 'off';
            app.sim_a3.Placeholder = '0';
            app.sim_a3.Value = 820;
            app.sim_alp3 = uieditfield(app.simGrid, 'numeric');
            app.sim_alp3.Editable = 'off';
            app.sim_alp3.Placeholder = '0';
            app.sim_d3 = uieditfield(app.simGrid, 'numeric');
            app.sim_d3.Editable = 'off';
            app.sim_d3.Placeholder = '0';
            app.sim_q3 = uieditfield(app.simGrid, 'numeric');
            app.sim_q3.Editable = 'off';
            app.sim_q3.Placeholder = '0';
            app.sim_q3.Value = 90;
            app.sim_a4 = uieditfield(app.simGrid, 'numeric');
            app.sim_a4.Editable = 'off';
            app.sim_a4.Placeholder = '0';
            app.sim_a4.Value = -210;
            app.sim_alp4 = uieditfield(app.simGrid, 'numeric');
            app.sim_alp4.Editable = 'off';
            app.sim_alp4.Placeholder = '0';
            app.sim_alp4.Value = 90;
            app.sim_d4 = uieditfield(app.simGrid, 'numeric');
            app.sim_d4.Editable = 'off';
            app.sim_d4.Placeholder = '0';
            app.sim_d4.Value = 1022;
            app.sim_q4 = uieditfield(app.simGrid, 'numeric');
            app.sim_q4.Editable = 'off';
            app.sim_q4.Placeholder = '0';
            app.sim_q4.Value = 90;
            app.sim_a5 = uieditfield(app.simGrid, 'numeric');
            app.sim_a5.Editable = 'off';
            app.sim_a5.Placeholder = '0';
            app.sim_alp5 = uieditfield(app.simGrid, 'numeric');
            app.sim_alp5.Editable = 'off';
            app.sim_alp5.Placeholder = '0';
            app.sim_d5 = uieditfield(app.simGrid, 'numeric');
            app.sim_d5.Editable = 'off';
            app.sim_d5.Placeholder = '0';
            app.sim_q5 = uieditfield(app.simGrid, 'numeric');
            app.sim_q5.Editable = 'off';
            app.sim_q5.Placeholder = '0';
            app.sim_a6 = uieditfield(app.simGrid, 'numeric');
            app.sim_a6.Editable = 'off';
            app.sim_a6.Placeholder = '0';
            app.sim_alp6 = uieditfield(app.simGrid, 'numeric');
            app.sim_alp6.Editable = 'off';
            app.sim_alp6.Placeholder = '0';
            app.sim_alp6.Value = 90;
            app.sim_d6 = uieditfield(app.simGrid, 'numeric');
            app.sim_d6.Editable = 'off';
            app.sim_d6.Placeholder = '0';
            app.sim_d6.Value = 115;
            app.sim_q6 = uieditfield(app.simGrid, 'numeric');
            app.sim_q6.Editable = 'off';
            app.sim_q6.Placeholder = '0';
            app.sim_q6.Value = 180;
            app.sim_line1Slider = uislider(app.simGrid);
            app.sim_line1Slider.Limits = [-180 180];
            app.sim_line1Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line1Slider.ValueChangingFcn = createCallbackFcn(app, @sim_line1SliderValueChanging, true);
            app.sim_line1Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line1Slider.Enable = 'off';
            app.sim_line2Slider = uislider(app.simGrid);
            app.sim_line2Slider.Limits = [-180 180];
            app.sim_line2Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line2Slider.ValueChangingFcn = createCallbackFcn(app, @sim_line2SliderValueChanging, true);
            app.sim_line2Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line2Slider.Enable = 'off';
            app.sim_line3Slider = uislider(app.simGrid);
            app.sim_line3Slider.Limits = [-180 180];
            app.sim_line3Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line3Slider.ValueChangingFcn = createCallbackFcn(app, @sim_line3SliderValueChanging, true);
            app.sim_line3Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line3Slider.Enable = 'off';
            app.sim_line4Slider = uislider(app.simGrid);
            app.sim_line4Slider.Limits = [-180 180];
            app.sim_line4Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line4Slider.ValueChangingFcn = createCallbackFcn(app, @sim_line4SliderValueChanging, true);
            app.sim_line4Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line4Slider.Enable = 'off';
            app.sim_line5Slider = uislider(app.simGrid);
            app.sim_line5Slider.Limits = [-180 180];
            app.sim_line5Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line5Slider.ValueChangingFcn = createCallbackFcn(app, @sim_line5SliderValueChanging, true);
            app.sim_line5Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line5Slider.Enable = 'off';
            app.sim_line6Slider = uislider(app.simGrid);
            app.sim_line6Slider.Limits = [-180 180];
            app.sim_line6Slider.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line6Slider.ValueChangingFcn = createCallbackFcn(app, @sim_line6SliderValueChanging, true);
            app.sim_line6Slider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.sim_line6Slider.Enable = 'off';
            app.sim_line1Degree = uieditfield(app.simGrid, 'numeric');
            app.sim_line1Degree.Limits = [-180 180];
            app.sim_line1Degree.ValueDisplayFormat = '%.2f';
            app.sim_line1Degree.ValueChangedFcn = createCallbackFcn(app, @sim_line1DegreeValueChanged, true);
            app.sim_line1Degree.Editable = 'off';
            app.sim_line1Degree.Enable = 'off';
            app.sim_line2Degree = uieditfield(app.simGrid, 'numeric');
            app.sim_line2Degree.Limits = [-180 180];
            app.sim_line2Degree.ValueDisplayFormat = '%.2f';
            app.sim_line2Degree.ValueChangedFcn = createCallbackFcn(app, @sim_line2DegreeValueChanged, true);
            app.sim_line2Degree.Editable = 'off';
            app.sim_line2Degree.Enable = 'off';
            app.sim_line3Degree = uieditfield(app.simGrid, 'numeric');
            app.sim_line3Degree.Limits = [-180 180];
            app.sim_line3Degree.ValueDisplayFormat = '%.2f';
            app.sim_line3Degree.ValueChangedFcn = createCallbackFcn(app, @sim_line3DegreeValueChanged, true);
            app.sim_line3Degree.Editable = 'off';
            app.sim_line3Degree.Enable = 'off';
            app.sim_line4Degree = uieditfield(app.simGrid, 'numeric');
            app.sim_line4Degree.Limits = [-180 180];
            app.sim_line4Degree.ValueDisplayFormat = '%.2f';
            app.sim_line4Degree.ValueChangedFcn = createCallbackFcn(app, @sim_line4DegreeValueChanged, true);
            app.sim_line4Degree.Editable = 'off';
            app.sim_line4Degree.Enable = 'off';
            app.sim_line5Degree = uieditfield(app.simGrid, 'numeric');
            app.sim_line5Degree.Limits = [-180 180];
            app.sim_line5Degree.ValueDisplayFormat = '%.2f';
            app.sim_line5Degree.ValueChangedFcn = createCallbackFcn(app, @sim_line5DegreeValueChanged, true);
            app.sim_line5Degree.Editable = 'off';
            app.sim_line5Degree.Enable = 'off';
            app.sim_line6Degree = uieditfield(app.simGrid, 'numeric');
            app.sim_line6Degree.Limits = [-180 180];
            app.sim_line6Degree.ValueDisplayFormat = '%.2f';
            app.sim_line6Degree.ValueChangedFcn = createCallbackFcn(app, @sim_line6DegreeValueChanged, true);
            app.sim_line6Degree.Editable = 'off';
            app.sim_line6Degree.Enable = 'off';
            app.sim_calistir = uibutton(app.simGrid, 'push');
            app.sim_calistir.ButtonPushedFcn = createCallbackFcn(app, @sim_calistirButtonPushed, true);
            app.sim_calistir.Text = 'ÇALIŞTIR';
            app.sim_durdur = uibutton(app.simGrid, 'push');
            app.sim_durdur.ButtonPushedFcn = createCallbackFcn(app, @sim_durdurButtonPushed, true);
            app.sim_durdur.Text = 'DURDUR';
            app.logo_2 = uiimage(app.simGrid);
            app.logo_2.ImageSource = fullfile(pathToMLAPP, 'logorgb.png');
            app.dersTitle_2 = uilabel(app.simGrid);
            app.dersTitle_2.Text = '2023-2024 Robotik ve Otomasyon Sistemleri';
            app.bolumTitle_2 = uilabel(app.simGrid);
            app.bolumTitle_2.Text = 'Mekatronik Mühendisliği Bölümü';
            app.sim_xTitle = uilabel(app.simGrid);
            app.sim_xTitle.Text = 'X';
            app.sim_yTitle = uilabel(app.simGrid);
            app.sim_yTitle.Text = 'Y';
            app.sim_zTitle = uilabel(app.simGrid);
            app.sim_zTitle.Text = 'Z';
            app.sim_xValue = uilabel(app.simGrid);
            app.sim_xValue.Text = '0';
            app.sim_yValue = uilabel(app.simGrid);
            app.sim_yValue.Text = '0';
            app.sim_zValue = uilabel(app.simGrid);
            app.sim_zValue.Text = '0';
            app.sim_yawTitle = uilabel(app.simGrid);
            app.sim_yawTitle.Text = 'Yaw';
            app.sim_pitchTitle = uilabel(app.simGrid);
            app.sim_pitchTitle.Text = 'Pitch';
            app.sim_rollTitle = uilabel(app.simGrid);
            app.sim_rollTitle.Text = 'Roll';
            app.sim_yawValue = uilabel(app.simGrid);
            app.sim_yawValue.Text = '0';
            app.sim_pitchValue = uilabel(app.simGrid);
            app.sim_pitchValue.Text = '0';
            app.sim_rollValue = uilabel(app.simGrid);
            app.sim_rollValue.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotLabProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end